classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        LoadFilesTab               matlab.ui.container.Tab
        KnownTargetsPanel          matlab.ui.container.Panel
        UITable                    matlab.ui.control.Table
        SedListBox                 matlab.ui.control.ListBox
        SedListBoxLabel            matlab.ui.control.Label
        LoadButton_3               matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        ImagesPanel                matlab.ui.container.Panel
        BSpinner                   matlab.ui.control.Spinner
        BSpinnerLabel              matlab.ui.control.Label
        GSpinner                   matlab.ui.control.Spinner
        GSpinnerLabel              matlab.ui.control.Label
        RSpinner                   matlab.ui.control.Spinner
        RSpinnerLabel              matlab.ui.control.Label
        ImagePreviewLabel          matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        WaveLenghtsEditField       matlab.ui.control.EditField
        WaveLenghtsEditFieldLabel  matlab.ui.control.Label
        ImagesListBox              matlab.ui.control.ListBox
        ImagesListBoxLabel         matlab.ui.control.Label
        LoadButton                 matlab.ui.control.Button
        FeaturesTab                matlab.ui.container.Tab
        ChannelLabel_3             matlab.ui.control.Label
        Channel_roi                matlab.ui.control.Spinner
        WavelengthLabel            matlab.ui.control.Label
        Wave                       matlab.ui.control.Spinner
        xTextArea                  matlab.ui.control.TextArea
        xTextAreaLabel             matlab.ui.control.Label
        bTextArea                  matlab.ui.control.TextArea
        bTextAreaLabel             matlab.ui.control.Label
        ATextArea                  matlab.ui.control.TextArea
        ATextAreaLabel             matlab.ui.control.Label
        dTextArea                  matlab.ui.control.TextArea
        dTextAreaLabel             matlab.ui.control.Label
        CTextArea                  matlab.ui.control.TextArea
        CTextAreaLabel             matlab.ui.control.Label
        SelectROIsButton           matlab.ui.control.Button
        ChannelLabel_2             matlab.ui.control.Label
        Channel_2                  matlab.ui.control.Spinner
        Img2ListBox                matlab.ui.control.ListBox
        Img2ListBoxLabel           matlab.ui.control.Label
        Img1ListBox                matlab.ui.control.ListBox
        Img1ListBoxLabel           matlab.ui.control.Label
        MatchFeaturesButton        matlab.ui.control.Button
        ChannelLabel               matlab.ui.control.Label
        Channel                    matlab.ui.control.Spinner
        NumberofPointsLabel        matlab.ui.control.Label
        ImagesListBox_2            matlab.ui.control.ListBox
        ImagesListBox_2Label       matlab.ui.control.Label
        UIAxes_DN                  matlab.ui.control.UIAxes
        UIAxes_points              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        imgs = [] % Full paths to imgs
        targets_rf = [] % Reflectance arrays for targets
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file_imgs,path_imgs] = uigetfile('*.tif','MultiSelect','on');  %open a tif files
            
            paths = fullfile(path_imgs, file_imgs);
            app.imgs = paths;
            app.ImagesListBox.Items = string(file_imgs);
            app.ImagesListBox.ItemsData = paths;
            app.ImagesListBox_2.Items = string(file_imgs);
            app.ImagesListBox_2.ItemsData = paths;
            
            app.Img1ListBox.Items = string(file_imgs);
            app.Img1ListBox.ItemsData = paths;
            
            app.Img2ListBox.Items = string(file_imgs);
            app.Img2ListBox.ItemsData = paths;
        end

        % Value changed function: WaveLenghtsEditField
        function WaveLenghtsEditFieldValueChanged(app, event)
            value = app.WaveLenghtsEditField.Value;
            value = str2num(value);
        end

        % Value changed function: RSpinner
        function RSpinnerValueChanged(app, event)
            value = app.RSpinner.Value;
            img = app.ImagesListBox.Value;
            raw_img = imread(img);
            custom_rgb_img = raw_img(:,:,[uint8(app.RSpinner.Value) uint8(app.GSpinner.Value) uint8(app.BSpinner.Value)]);
            app.Image.ImageSource = custom_rgb_img;
            
        end

        % Value changed function: GSpinner
        function GSpinnerValueChanged(app, event)
            value = app.GSpinner.Value;
            img = app.ImagesListBox.Value;
            raw_img = imread(img);
            custom_rgb_img = raw_img(:,:,[uint8(app.RSpinner.Value) uint8(app.GSpinner.Value) uint8(app.BSpinner.Value)]);
            app.Image.ImageSource = custom_rgb_img;
            
        end

        % Value changed function: BSpinner
        function BSpinnerValueChanged(app, event)
            value = app.BSpinner.Value;
            img = app.ImagesListBox.Value;
            raw_img = imread(img);
            custom_rgb_img = raw_img(:,:,[uint8(app.RSpinner.Value) uint8(app.GSpinner.Value) uint8(app.BSpinner.Value)]);
            app.Image.ImageSource = custom_rgb_img;
            
        end

        % Value changed function: ImagesListBox
        function ImagesListBoxValueChanged(app, event)
            value = app.ImagesListBox.Value;
            raw_img = imread(value);
            custom_rgb_img = raw_img(:,:,[uint8(app.RSpinner.Value) uint8(app.GSpinner.Value) uint8(app.BSpinner.Value)]);
            app.Image.ImageSource = custom_rgb_img;
%             display(value)
        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            [file_sed,path_sed] = uigetfile('*.sed','MultiSelect','on');  %open a tif files
            app.SedListBox.Items = string(file_sed);
            app.SedListBox.ItemsData = fullfile(path_sed, file_sed);
            paths = fullfile(path_sed, file_sed);
            sed_arrays = {};
            for k=1:length(paths)
                value = paths{1,k};
                fileID = fopen(value);
                t = textscan(fileID,'%f%f%f%f','HeaderLines',33,'Delimiter','\t');
                fclose(fileID);
                sed_arrays{end+1} = [t{1,:}];
            end
            app.targets_rf = sed_arrays;
        end

        % Value changed function: SedListBox
        function SedListBoxValueChanged(app, event)
            value = app.SedListBox.Value;
            fileID = fopen(value);
            t = textscan(fileID,'%f%f%f%f','HeaderLines',33,'Delimiter','\t');
            fclose(fileID);
%             index = find(t{1,1}==432)
%             r = t{1,4}(index,1)/100
            app.UITable.Data = [t{1,:}]
%             app.UIAxes.XLimMode = 'auto'
            plot(app.UIAxes,t{1,1},t{1,4});
            yline(app.UIAxes,100);
            
        end

        % Button pushed function: MatchFeaturesButton
        function MatchFeaturesButtonPushed(app, event)
            i = uint8(app.Channel_2.Value);
            
            value1 = app.Img1ListBox.Value;
            value2 = app.Img2ListBox.Value;
            
            img1 = imread(value1);
            img2 = imread(value2);
            
            I1 = im2gray(img1(:,:,i));
            I2 = im2gray(img2(:,:,i));
            
            points1 = detectHarrisFeatures(I1);
            points2 = detectHarrisFeatures(I2);
            
            [features1,valid_points1] = extractFeatures(I1,points1);
            [features2,valid_points2] = extractFeatures(I2,points2);
            
            indexPairs = matchFeatures(features1,features2);
            
            matchedPoints1 = valid_points1(indexPairs(:,1),:);
            matchedPoints2 = valid_points2(indexPairs(:,2),:);
            
            locations_img1 = round(matchedPoints1.Location);
            locations_img2 = round(matchedPoints2.Location);
            
            values1 = [];
            values2 = [];
            
            for i=1:length(locations_img1(:,1))
                values1(end+1) = I1(locations_img1(i,2),locations_img1(i,1));
                values2(end+1) = I2(locations_img2(i,2),locations_img2(i,1));
            end
            
            
            scatter(app.UIAxes_DN,values1,values2);
            
        end

        % Value changed function: ImagesListBox_2
        function ImagesListBox_2ValueChanged(app, event)
            i = uint8(app.Channel.Value);
            
            value = app.ImagesListBox_2.Value;
            raw_img = imread(value);
            
            points1 = detectSURFFeatures(raw_img(:,:,i));
            app.NumberofPointsLabel.Text = join(['Number of Points:',string(points1.Count)]);
            imshow(raw_img(:,:,i),'Parent',app.UIAxes_points);
%             title([string(wavelengthRange(i)),'nm, Pnts: ',string(nOfPoints)])
            hold(app.UIAxes_points,"on");
            title(app.UIAxes_points,"");
            xlabel(app.UIAxes_points,"");
            ylabel(app.UIAxes_points,"");
            points  = points1.Location;
            scatter(points(:,1),points(:,2),'Parent',app.UIAxes_points);
            hold(app.UIAxes_points,"off");
            
        end

        % Value changing function: Channel
        function ChannelValueChanging(app, event)
            changingValue = event.Value;
            ImagesListBox_2ValueChanged(app, event);
        end

        % Button pushed function: SelectROIsButton
        function SelectROIsButtonPushed(app, event)
            C = zeros([5,4]);
            d = [];
            A = [];
            b = [1;1;0;0];
            chn = uint8(app.Channel_roi.Value);
            wave = uint16(app.Wave.Value);
            test = app.targets_rf{1,1};
            index = find(app.targets_rf{1,1}(:,1)==wave);
            t_rf_b = app.targets_rf{1,1}(index,4)/100;
            t_rf_g = app.targets_rf{1,2}(index,4)/100;
            t_rf_r = app.targets_rf{1,3}(index,4)/100;
            
            d = [t_rf_b;t_rf_g;t_rf_r;0;0];
            
            targets = ['Black','Gray','Red'];
            % Targets
            for i=1:2
                for j=1:3
                    I = imread(app.imgs{1,i});
                    I = I(:,:,chn);
                    imshow(I);
                    roi = drawpolygon('Color','r');
                    xi = roi.Position(:,1);
                    yi = roi.Position(:,2);
                    close;
                    [m,n] = size(I);
                    mask = poly2mask(xi,yi,m,n);
                    mean_DN = mean(I(mask));
                    if i==1
                       C(j,i:i+1) = [mean_DN,1];
                    else
                       C(j,i+1:i+2) = [mean_DN,1];
                    end
                     
                end
            end
            % Tie points
            i = chn;
            
            value1 = app.Img1ListBox.Value;
            value2 = app.Img2ListBox.Value;
            
            img1 = imread(value1);
            img2 = imread(value2);
            
            I1 = im2gray(img1(:,:,i));
            I2 = im2gray(img2(:,:,i));
            
            points1 = detectHarrisFeatures(I1);
            points2 = detectHarrisFeatures(I2);
            
            [features1,valid_points1] = extractFeatures(I1,points1);
            [features2,valid_points2] = extractFeatures(I2,points2);
            
            for tr=1:1:15
                indexPairs = matchFeatures(features1,features2,"Unique",true,"MatchThreshold",tr);
                if length(indexPairs) >=2
                    break
                end
            end
            
            matchedPoints1 = valid_points1(indexPairs(:,1),:);
            matchedPoints2 = valid_points2(indexPairs(:,2),:);
            
            locations_img1 = round(matchedPoints1.Location);
            locations_img2 = round(matchedPoints2.Location);
            
            values1 = [];
            values2 = [];
            
            for i=1:length(locations_img1(:,1))
                values1(end+1) = I1(locations_img1(i,2),locations_img1(i,1));
                values2(end+1) = I2(locations_img2(i,2),locations_img2(i,1));
            end
            
            C(4:5,1:2) = [-values1(1,1),-1;-values1(1,2),-1];
            C(4:5,3:4) = [values2(1,1),1;values1(1,2),1];
            
            A = zeros([4,4]);
            A(1,1:2) = [max(I1, [], 'all'),1];
            A(2,3:4) = [max(I2, [], 'all'),1];
            A(3,1:2) = [-min(I1, [], 'all'),-1];
            A(4,3:4) = [-min(I2, [], 'all'),-1];
            
            app.CTextArea.Value = mat2str(C);
            app.dTextArea.Value = mat2str(d);
            app.ATextArea.Value = mat2str(A);
            app.bTextArea.Value = mat2str(b);
            
            x = lsqlin(C,d,A,b);
            app.xTextArea.Value = mat2str(x);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadFilesTab = uitab(app.TabGroup);
            app.LoadFilesTab.Title = 'Load Files';
            app.ImagesPanel = uipanel(app.LoadFilesTab);
            app.ImagesPanel.Title = 'Images';
            app.LoadButton = uibutton(app.ImagesPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ImagesListBoxLabel = uilabel(app.ImagesPanel);
            app.ImagesListBoxLabel.Text = 'Images';
            app.ImagesListBox = uilistbox(app.ImagesPanel);
            app.ImagesListBox.Items = {};
            app.ImagesListBox.ValueChangedFcn = createCallbackFcn(app, @ImagesListBoxValueChanged, true);
            app.ImagesListBox.Value = {};
            app.WaveLenghtsEditFieldLabel = uilabel(app.ImagesPanel);
            app.WaveLenghtsEditFieldLabel.Text = 'Wave Lenghts';
            app.WaveLenghtsEditField = uieditfield(app.ImagesPanel, 'text');
            app.WaveLenghtsEditField.ValueChangedFcn = createCallbackFcn(app, @WaveLenghtsEditFieldValueChanged, true);
            app.WaveLenghtsEditField.Tooltip = {'450,451,452'};
            app.Image = uiimage(app.ImagesPanel);
            app.ImagePreviewLabel = uilabel(app.ImagesPanel);
            app.ImagePreviewLabel.Text = 'Image Preview';
            app.RSpinnerLabel = uilabel(app.ImagesPanel);
            app.RSpinnerLabel.Text = 'R';
            app.RSpinner = uispinner(app.ImagesPanel);
            app.RSpinner.Limits = [1 Inf];
            app.RSpinner.ValueChangedFcn = createCallbackFcn(app, @RSpinnerValueChanged, true);
            app.RSpinner.Value = 11;
            app.GSpinnerLabel = uilabel(app.ImagesPanel);
            app.GSpinnerLabel.Text = 'G';
            app.GSpinner = uispinner(app.ImagesPanel);
            app.GSpinner.Limits = [1 Inf];
            app.GSpinner.ValueChangedFcn = createCallbackFcn(app, @GSpinnerValueChanged, true);
            app.GSpinner.Value = 7;
            app.BSpinnerLabel = uilabel(app.ImagesPanel);
            app.BSpinnerLabel.Text = 'B';
            app.BSpinner = uispinner(app.ImagesPanel);
            app.BSpinner.Limits = [1 Inf];
            app.BSpinner.ValueChangedFcn = createCallbackFcn(app, @BSpinnerValueChanged, true);
            app.BSpinner.Value = 2;
            app.KnownTargetsPanel = uipanel(app.LoadFilesTab);
            app.KnownTargetsPanel.Title = 'Known Targets';
            app.UIAxes = uiaxes(app.KnownTargetsPanel);
            title(app.UIAxes, 'Reflectance')
            xlabel(app.UIAxes, 'Wavelength(nm)')
            ylabel(app.UIAxes, 'R(%)')
            zlabel(app.UIAxes, 'Z')
            app.LoadButton_3 = uibutton(app.KnownTargetsPanel, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Text = 'Load';
            app.SedListBoxLabel = uilabel(app.KnownTargetsPanel);
            app.SedListBoxLabel.Text = 'Sed';
            app.SedListBox = uilistbox(app.KnownTargetsPanel);
            app.SedListBox.Items = {};
            app.SedListBox.ValueChangedFcn = createCallbackFcn(app, @SedListBoxValueChanged, true);
            app.SedListBox.Value = {};
            app.UITable = uitable(app.KnownTargetsPanel);
            app.UITable.ColumnName = {'Wvl'; 'Rad. (Ref.)'; 'Rad. (Target)'; 'Reflect. %'};
            app.UITable.RowName = {};
            app.FeaturesTab = uitab(app.TabGroup);
            app.FeaturesTab.Title = 'Features';
            app.UIAxes_points = uiaxes(app.FeaturesTab);
            app.UIAxes_points.XTick = [];
            app.UIAxes_points.XTickLabel = {};
            app.UIAxes_points.YTick = [];
            app.UIAxes_points.YTickLabel = {};
            app.UIAxes_points.ZTick = [];
            app.UIAxes_points.ZTickLabel = '';
            app.UIAxes_DN = uiaxes(app.FeaturesTab);
            title(app.UIAxes_DN, 'Corelation')
            xlabel(app.UIAxes_DN, 'Img1')
            ylabel(app.UIAxes_DN, 'Img2')
            zlabel(app.UIAxes_DN, 'Z')
            app.ImagesListBox_2Label = uilabel(app.FeaturesTab);
            app.ImagesListBox_2Label.Text = 'Images';
            app.ImagesListBox_2 = uilistbox(app.FeaturesTab);
            app.ImagesListBox_2.Items = {};
            app.ImagesListBox_2.ValueChangedFcn = createCallbackFcn(app, @ImagesListBox_2ValueChanged, true);
            app.ImagesListBox_2.Value = {};
            app.NumberofPointsLabel = uilabel(app.FeaturesTab);
            app.NumberofPointsLabel.Text = 'Number of Points:';
            app.Channel = uispinner(app.FeaturesTab);
            app.Channel.ValueChangingFcn = createCallbackFcn(app, @ChannelValueChanging, true);
            app.Channel.Limits = [1 Inf];
            app.Channel.Value = 1;
            app.ChannelLabel = uilabel(app.FeaturesTab);
            app.ChannelLabel.Text = 'Channel';
            app.MatchFeaturesButton = uibutton(app.FeaturesTab, 'push');
            app.MatchFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @MatchFeaturesButtonPushed, true);
            app.MatchFeaturesButton.Text = 'Match Features';
            app.Img1ListBoxLabel = uilabel(app.FeaturesTab);
            app.Img1ListBoxLabel.Text = 'Img1';
            app.Img1ListBox = uilistbox(app.FeaturesTab);
            app.Img1ListBox.Items = {};
            app.Img1ListBox.Value = {};
            app.Img2ListBoxLabel = uilabel(app.FeaturesTab);
            app.Img2ListBoxLabel.Text = 'Img2';
            app.Img2ListBox = uilistbox(app.FeaturesTab);
            app.Img2ListBox.Items = {};
            app.Img2ListBox.Value = {};
            app.Channel_2 = uispinner(app.FeaturesTab);
            app.Channel_2.Limits = [1 Inf];
            app.Channel_2.Value = 1;
            app.ChannelLabel_2 = uilabel(app.FeaturesTab);
            app.ChannelLabel_2.Text = 'Channel';
            app.SelectROIsButton = uibutton(app.FeaturesTab, 'push');
            app.SelectROIsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIsButtonPushed, true);
            app.SelectROIsButton.Text = 'Select ROIs';
            app.CTextAreaLabel = uilabel(app.FeaturesTab);
            app.CTextAreaLabel.Text = 'C';
            app.CTextArea = uitextarea(app.FeaturesTab);
            app.dTextAreaLabel = uilabel(app.FeaturesTab);
            app.dTextAreaLabel.Text = 'd';
            app.dTextArea = uitextarea(app.FeaturesTab);
            app.ATextAreaLabel = uilabel(app.FeaturesTab);
            app.ATextAreaLabel.Text = 'A';
            app.ATextArea = uitextarea(app.FeaturesTab);
            app.bTextAreaLabel = uilabel(app.FeaturesTab);
            app.bTextAreaLabel.Text = 'b';
            app.bTextArea = uitextarea(app.FeaturesTab);
            app.xTextAreaLabel = uilabel(app.FeaturesTab);
            app.xTextAreaLabel.Text = 'x';
            app.xTextArea = uitextarea(app.FeaturesTab);
            app.Wave = uispinner(app.FeaturesTab);
            app.Wave.Limits = [1 Inf];
            app.Wave.Value = 560;
            app.WavelengthLabel = uilabel(app.FeaturesTab);
            app.WavelengthLabel.Text = 'Wavelength';
            app.Channel_roi = uispinner(app.FeaturesTab);
            app.Channel_roi.Limits = [1 Inf];
            app.Channel_roi.Value = 7;
            app.ChannelLabel_3 = uilabel(app.FeaturesTab);
            app.ChannelLabel_3.Text = 'Channel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end